classdef MainGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Switch              matlab.ui.control.ToggleSwitch
        SwitchLabel         matlab.ui.control.Label
        LoadSimulateButton  matlab.ui.control.Button
        Button              matlab.ui.control.Button
    end

    
    properties (Access = public)
        % Handle to the TeachPendant App:
        TeachPendantAppHandle = [];

        % Data member of this class:
        tm5Robot;
        tm5qHome = [pi, -pi/2, -pi/2, -pi/2, pi/2, 0];
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.TeachPendantAppHandle = TeachPendant(app, app.tm5qHome);
        end

        % Button pushed function: LoadSimulateButton
        function LoadSimulateButtonPushed(app, event)
            % Call the setup environment function:
            SetupEnvironment(eye(4));
            hold on;

            app.tm5Robot = TM5(eye(4), app.tm5qHome, [-1.5 1.5, -1.5, 1.5, 0, 2]);
            drawnow


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.LoadSimulateButton = uibutton(app.UIFigure, 'push');
            app.LoadSimulateButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSimulateButtonPushed, true);
            app.LoadSimulateButton.Text = 'Load Simulate';
            app.SwitchLabel = uilabel(app.UIFigure);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.UIFigure, 'toggle');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainGui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end